classdef mcl_mpl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCLCalculatorUIFigure        matlab.ui.Figure
        Image                        matlab.ui.control.Image
        NoiseFigureSliderLabel       matlab.ui.control.Label
        NoiseFigureSlider            matlab.ui.control.Slider
        RequiredSINREditFieldLabel   matlab.ui.control.Label
        SINRNum                      matlab.ui.control.NumericEditField
        ReceiversensitivityLabel     matlab.ui.control.Label
        TxPowerEditFieldLabel        matlab.ui.control.Label
        TxPowNum                     matlab.ui.control.NumericEditField
        TxAntennaGainEditFieldLabel  matlab.ui.control.Label
        TxGainNum                    matlab.ui.control.NumericEditField
        EIRPLabel                    matlab.ui.control.Label
        MaxCouplinkLossLabel         matlab.ui.control.Label
        MaxPathLossLabel             matlab.ui.control.Label
        ThermalNoiseFloorLabel       matlab.ui.control.Label
        BandwidthEditFieldLabel      matlab.ui.control.Label
        BandwidthNum                 matlab.ui.control.NumericEditField
        kHzLabel                     matlab.ui.control.Label
        dBmLabel                     matlab.ui.control.Label
        dBiLabel                     matlab.ui.control.Label
        dBLabel_2                    matlab.ui.control.Label
        RxAntennaGainLabel           matlab.ui.control.Label
        RxGainNum                    matlab.ui.control.NumericEditField
        dBiLabel_2                   matlab.ui.control.Label
        TransmitterLabel             matlab.ui.control.Label
        ReceiverLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        txPow = 23;
        txGain = 0;
        rxGain = 0;
        sinr = -11.8;
        noiseFigure = 9;
        bandwidth = 3.5;
    end
    
    methods (Access = private)
        
        function calculateMCLMPL(app)
            noiseFloor = -174 + 10 * log10(app.bandwidth * 1000);
            recSensitivity = app.noiseFigure + app.sinr + noiseFloor;
            mcl = app.txPow - (app.noiseFigure + app.sinr + noiseFloor);
            mpl = app.txPow + app.txGain + app.rxGain - (app.noiseFigure + app.sinr + noiseFloor);
            app.EIRPLabel.Text = sprintf('Radiated Power (EIRP): %.2f dBm', app.txPow + app.txGain);
            app.MaxCouplinkLossLabel.Text = sprintf('Max Couplink Loss: %.2f dB', mcl);
            app.MaxPathLossLabel.Text = sprintf('Max Path Loss: %.2f dB', mpl);
            app.ReceiversensitivityLabel.Text = sprintf('Receiver sensitivity: %.2f dBm', recSensitivity);
            app.ThermalNoiseFloorLabel.Text = sprintf('Thermal Noise Floor: %.2f dBm', noiseFloor);
        end
        
        function readValues(app)
            app.txPow = app.TxPowNum.Value;
            app.txGain = app.TxGainNum.Value;
            app.rxGain = app.RxGainNum.Value;
            app.sinr = app.SINRNum.Value;
            app.noiseFigure = app.NoiseFigureSlider.Value;
            app.bandwidth = app.BandwidthNum.Value;
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.calculateMCLMPL();
        end

        % Value changed function: BandwidthNum, NoiseFigureSlider, 
        % RxGainNum, SINRNum, TxGainNum, TxPowNum
        function ValueChanged(app, event)
            app.readValues();
            app.calculateMCLMPL();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MCLCalculatorUIFigure = uifigure('Visible', 'off');
            app.MCLCalculatorUIFigure.Name = 'MCL Calculator';
            app.Image = uiimage(app.MCLCalculatorUIFigure);
            app.Image.ImageSource = 'mcl.svg';
            app.NoiseFigureSliderLabel = uilabel(app.MCLCalculatorUIFigure);
            app.NoiseFigureSliderLabel.Text = 'Noise Figure';
            app.NoiseFigureSlider = uislider(app.MCLCalculatorUIFigure);
            app.NoiseFigureSlider.Limits = [3 10];
            app.NoiseFigureSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.NoiseFigureSlider.Value = 9;
            app.RequiredSINREditFieldLabel = uilabel(app.MCLCalculatorUIFigure);
            app.RequiredSINREditFieldLabel.Text = 'Required SINR';
            app.SINRNum = uieditfield(app.MCLCalculatorUIFigure, 'numeric');
            app.SINRNum.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.SINRNum.Value = -11.8;
            app.ReceiversensitivityLabel = uilabel(app.MCLCalculatorUIFigure);
            app.ReceiversensitivityLabel.Text = 'Receiver sensitivity: -119.05 dBm';
            app.TxPowerEditFieldLabel = uilabel(app.MCLCalculatorUIFigure);
            app.TxPowerEditFieldLabel.Text = 'Tx Power';
            app.TxPowNum = uieditfield(app.MCLCalculatorUIFigure, 'numeric');
            app.TxPowNum.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.TxPowNum.Value = 23;
            app.TxAntennaGainEditFieldLabel = uilabel(app.MCLCalculatorUIFigure);
            app.TxAntennaGainEditFieldLabel.Text = 'Tx Antenna Gain';
            app.TxGainNum = uieditfield(app.MCLCalculatorUIFigure, 'numeric');
            app.TxGainNum.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.EIRPLabel = uilabel(app.MCLCalculatorUIFigure);
            app.EIRPLabel.Text = 'Radiated Power (EIRP): 23 dBm';
            app.MaxCouplinkLossLabel = uilabel(app.MCLCalculatorUIFigure);
            app.MaxCouplinkLossLabel.Text = 'Max Couplink Loss: 142.05 dB';
            app.MaxPathLossLabel = uilabel(app.MCLCalculatorUIFigure);
            app.MaxPathLossLabel.Text = 'Max Path Loss: 142.05 dB';
            app.ThermalNoiseFloorLabel = uilabel(app.MCLCalculatorUIFigure);
            app.ThermalNoiseFloorLabel.Text = 'Thermal Noise Floor: -121.45 dBm';
            app.BandwidthEditFieldLabel = uilabel(app.MCLCalculatorUIFigure);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthNum = uieditfield(app.MCLCalculatorUIFigure, 'numeric');
            app.BandwidthNum.Limits = [0 Inf];
            app.BandwidthNum.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.BandwidthNum.Value = 3.5;
            app.kHzLabel = uilabel(app.MCLCalculatorUIFigure);
            app.kHzLabel.Text = 'kHz';
            app.dBmLabel = uilabel(app.MCLCalculatorUIFigure);
            app.dBmLabel.Text = 'dBm';
            app.dBiLabel = uilabel(app.MCLCalculatorUIFigure);
            app.dBiLabel.Text = 'dBi';
            app.dBLabel_2 = uilabel(app.MCLCalculatorUIFigure);
            app.dBLabel_2.Text = 'dB';
            app.RxAntennaGainLabel = uilabel(app.MCLCalculatorUIFigure);
            app.RxAntennaGainLabel.Text = 'Rx Antenna Gain';
            app.RxGainNum = uieditfield(app.MCLCalculatorUIFigure, 'numeric');
            app.RxGainNum.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.dBiLabel_2 = uilabel(app.MCLCalculatorUIFigure);
            app.dBiLabel_2.Text = 'dBi';
            app.TransmitterLabel = uilabel(app.MCLCalculatorUIFigure);
            app.TransmitterLabel.Text = 'Transmitter';
            app.ReceiverLabel = uilabel(app.MCLCalculatorUIFigure);
            app.ReceiverLabel.Text = 'Receiver';
            % Show the figure after all components are created
            app.MCLCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mcl_mpl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCLCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCLCalculatorUIFigure)
        end
    end
end